classdef RoastGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        MainmenuTab                   matlab.ui.container.Tab
        LoadsettingsButton            matlab.ui.control.Button
        SavesettingsButton            matlab.ui.control.Button
        OptionalSelectT2MRIfileLabel  matlab.ui.control.Label
        SelectT1MRIfileLabel          matlab.ui.control.Label
        T2FileLoc                     matlab.ui.control.EditField
        BrowseButton_2                matlab.ui.control.Button
        T1FileLoc                     matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ElectrodeTabGroup             matlab.ui.container.TabGroup
        ElecTab                       matlab.ui.container.Tab
        PositionEditField             matlab.ui.control.EditField
        PositionEditFieldLabel        matlab.ui.control.Label
        StimulationintensitymAEditField  matlab.ui.control.NumericEditField
        StimulationintensitymAEditFieldLabel  matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        ElectrodeconfigurationLabel   matlab.ui.control.Label
        NumberofelectrodesEditField   matlab.ui.control.NumericEditField
        NumberofelectrodesEditFieldLabel  matlab.ui.control.Label
        CaptypeDropDown               matlab.ui.control.DropDown
        CaptypeDropDownLabel          matlab.ui.control.Label
        AdvancedsettingsTab           matlab.ui.container.Tab
        TabGroup4                     matlab.ui.container.TabGroup
        ConductivityvaluesTab         matlab.ui.container.Tab
        SmLabel_8                     matlab.ui.control.Label
        SmLabel_7                     matlab.ui.control.Label
        SmLabel_6                     matlab.ui.control.Label
        SmLabel_5                     matlab.ui.control.Label
        SmLabel_4                     matlab.ui.control.Label
        SmLabel_3                     matlab.ui.control.Label
        SmLabel_2                     matlab.ui.control.Label
        SmLabel                       matlab.ui.control.Label
        ElectrodeEditField            matlab.ui.control.NumericEditField
        ElectrodeEditFieldLabel       matlab.ui.control.Label
        GelEditField                  matlab.ui.control.NumericEditField
        GelEditFieldLabel             matlab.ui.control.Label
        AirEditField                  matlab.ui.control.NumericEditField
        AirEditFieldLabel             matlab.ui.control.Label
        SkinEditField                 matlab.ui.control.NumericEditField
        SkinEditFieldLabel            matlab.ui.control.Label
        BoneEditField                 matlab.ui.control.NumericEditField
        BoneEditFieldLabel            matlab.ui.control.Label
        CerebrospinalfluidEditField   matlab.ui.control.NumericEditField
        CerebrospinalfluidLabel       matlab.ui.control.Label
        GraymatterEditField           matlab.ui.control.NumericEditField
        GraymatterEditFieldLabel      matlab.ui.control.Label
        WhitematterEditField          matlab.ui.control.NumericEditField
        WhitematterEditFieldLabel     matlab.ui.control.Label
        MeshoptionsTab                matlab.ui.container.Tab
        MaximaltetrahedralelementvolumeEditField  matlab.ui.control.NumericEditField
        MaximaltetrahedralelementvolumeEditFieldLabel  matlab.ui.control.Label
        MaximalradiusedgeratioEditField  matlab.ui.control.NumericEditField
        MaximalradiusedgeratioEditFieldLabel  matlab.ui.control.Label
        MaximumdistancebetweenEditField  matlab.ui.control.NumericEditField
        MaximumdistancebetweenEditFieldLabel  matlab.ui.control.Label
        MinimalangleofasurfacetriangleEditField  matlab.ui.control.NumericEditField
        MinimalangleofasurfacetriangleEditFieldLabel  matlab.ui.control.Label
        MaximalsurfaceelementsizeEditField  matlab.ui.control.NumericEditField
        MaximalsurfaceelementsizeEditFieldLabel  matlab.ui.control.Label
        ZeropaddingEditField          matlab.ui.control.NumericEditField
        ZeropaddingEditFieldLabel     matlab.ui.control.Label
        ResamplingCheckBox            matlab.ui.control.CheckBox
        SimulationtagEditField        matlab.ui.control.EditField
        SimulationtagEditFieldLabel   matlab.ui.control.Label
        InfoTab                       matlab.ui.container.Tab
        AcknowledgementsLabel         matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        HelpfullinksLabel_2           matlab.ui.control.Label
        Hyperlink3_2                  matlab.ui.control.Hyperlink
        Hyperlink3                    matlab.ui.control.Hyperlink
        Hyperlink2                    matlab.ui.control.Hyperlink
        Hyperlink                     matlab.ui.control.Hyperlink
        RightPanel                    matlab.ui.container.Panel
        TabGroup3                     matlab.ui.container.TabGroup
        ElectrodepositionTab          matlab.ui.container.Tab
        ClicktoselectLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        DynamicElecTab % Electrode settings tab
        ElecPosLabel % Electrode position
        ElecPosValue
        ElecMag
        ElecMagValue
        ElecTypeLabel
        ElecTypeDropDown
        
        ElecPosSelect
        
        Image 
        ElecImg
        
        DyanmicStimIntensity % Stimulation intensity
        DynamicElecType % Electrode type
        DynamicOrientationDropDown
        DynamicOrientationText
    end


    methods (Access = private)
        % Print message to next line
%         function PrintToScreen(app, text)
%             if ~strcmp(class(text), 'char') %Ensure text is of class char
%                 text = char(text);
%             end
%             numLines = length(app.CurrentprogressTextArea.Value); % Count number of lines
%             app.CurrentprogressTextArea.Value{numLines+1} = text; % Add text to next line
%         end
        
        % Dynamicaly create new electrode settings tabs as the user
        % requests them
        function CreateElecTab(app, TabNum)
            % Create tab
            app.DynamicElecTab{TabNum} = uitab(app.ElectrodeTabGroup);
            app.DynamicElecTab{TabNum}.Title = string(TabNum);
            
            % Create electrode position text
            app.ElecPosLabel{TabNum} = uilabel(app.DynamicElecTab{TabNum});
            app.ElecPosLabel{TabNum}.Position = [6 115 43 22];
            app.ElecPosLabel{TabNum}.Text = 'Position';

            % Create PositionEditField
            app.ElecPosValue{TabNum} = uieditfield(app.DynamicElecTab{TabNum}, 'text');
            app.ElecPosValue{TabNum}.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.ElecPosValue{TabNum}.Position = [168 115 102 22];
            
            % Create stimulation magnitude text
            app.ElecMag{TabNum} = uilabel(app.DynamicElecTab{TabNum});
            app.ElecMag{TabNum} .Position = [6 80 142 22];
            app.ElecMag{TabNum} .Text = 'Stimulation intensity (mA)';

            % Create stimulation magnitude editable field
            app.ElecMagValue{TabNum} = uieditfield(app.DynamicElecTab{TabNum}, 'numeric');
            app.ElecMagValue{TabNum}.HorizontalAlignment = 'left';
            app.ElecMagValue{TabNum}.Position = [168 80 103 22];
            
            % Create TypeDropDownLabel
            app.ElecTypeLabel{TabNum} = uilabel(app.DynamicElecTab{TabNum});
            app.ElecTypeLabel{TabNum}.Position = [6 45 30 22];
            app.ElecTypeLabel{TabNum}.Text = 'Type';

            % Create TypeDropDown
            app.ElecTypeDropDown{TabNum} = uidropdown(app.DynamicElecTab{TabNum});
            app.ElecTypeDropDown{TabNum}.Items = {'Disc (default)', 'Pad', 'Ring'};
            app.ElecTypeDropDown{TabNum}.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ElecTypeDropDown{TabNum}.Position = [168 45 103 22];
            app.ElecTypeDropDown{TabNum}.Value = 'Disc (default)';

        end

        function DeleteElecTab(app, TabNum)
            % Delete electrode configuration tab requested
            delete(app.DynamicElecTab{TabNum});
            app.DynamicElecTab(TabNum) = [];
            
            delete(app.ElecPosLabel{TabNum});
            app.ElecPosLabel(TabNum) = [];
            
            delete(app.ElecPosValue{TabNum});
            app.ElecPosValue(TabNum) = [];
            
            delete(app.ElecMag{TabNum});
            app.ElecMag(TabNum) = [];
            
            delete(app.ElecMagValue{TabNum});
            app.ElecMagValue(TabNum) = [];
            
            delete(app.ElecTypeLabel{TabNum});
            app.ElecTypeLabel(TabNum) = [];
            
            delete(app.ElecTypeDropDown{TabNum});
            app.ElecTypeDropDown(TabNum) = [];
        end
        
        function customUpdateAppLayout(app, event)
            % Change right tab size to match window
            app.TabGroup3.Position(1) = 5;
            app.TabGroup3.Position(2) = 5;
            app.TabGroup3.Position(3) = app.UIFigure.Position(3) - 325;
            app.TabGroup3.Position(4) = app.UIFigure.Position(4) - 10;
            
            % If electrode position tab selected, change image size to
            % match window
            if strcmp(app.TabGroup3.SelectedTab.Title,'Electrode position')
                app.Image.Position(1) = 0;
                app.Image.Position(2) = 5;
                app.Image.Position(3) = app.TabGroup3.Position(3);
                app.Image.Position(4) = app.TabGroup3.Position(4) - 40;
            end
        end
        
        function HighlightElectrodePos(app, PosToUpdate)
            % Convert image position in % to pixel number
            imgRatio =  683 / 638;
            tabRatio = app.TabGroup3.Position(3) / app.TabGroup3.Position(4);

            if strcmp(app.CaptypeDropDown.Value, '1010 (default)')
               if tabRatio > imgRatio % Spaces to the side
                   ElecSize = (app.TabGroup3.Position(4)*(1/imgRatio))/9;
               else
                   ElecSize = (app.TabGroup3.Position(3)*imgRatio)/9;
               end
                
               if strcmp(PosToUpdate, 'F3')
                   pos = [100, 100];
               else
                   pos = [205, 215];
               end
            end
            % Create Image
            app.ElecImg = uiimage(app.ElectrodepositionTab);
            app.ElecImg.Position = [pos(1), pos(2), ElecSize, ElecSize];
            
            % Set image to be of specified electrode
            if str2double(app.ElectrodeTabGroup.SelectedTab.Title) == 1
                ElecType = app.TypeDropDown.Value;
            else
                ElecType = app.ElecTypeDropDown{str2double(app.ElectrodeTabGroup.SelectedTab.Title)-1}.Value;
            end
            
            if strcmp(ElecType, 'Disc (default)')
                imageFile = '\Images\Disc.png';
            elseif strcmp(ElecType, 'Pad')
                imageFile = '\Images\Pad.png';
            elseif strcmp(ElecType, 'Ring')
                imageFile = '\Images\Ring.png';
            end
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            imagePath = fullfile(absPath, imageFile); % absolute path to the image file
            app.ElecImg.ImageSource = imagePath;
            
            % Create text label for the electrode number
            app.ElecPosSelect = uilabel(app.ElectrodepositionTab);
            app.ElecPosSelect.Position = [0, 0, pos(1), pos(2)];
            app.ElecPosSelect.Text = app.ElectrodeTabGroup.SelectedTab.Title;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create Image
            app.Image = uiimage(app.ElectrodepositionTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Position = [0 5 app.TabGroup3.Position(3) app.TabGroup3.Position(4)-40];
            imageFile = '\Images\1020EEG.png';
            absPath = fileparts(mfilename('fullpath')); % absolute path to the folder containing the mlapp file
            imagePath = fullfile(absPath, imageFile); % absolute path to the image file
            app.Image.ImageSource = imagePath;
            app.GridLayout.ColumnWidth = {314, '2x'};
            app.UIFigure.Name = 'ROAST: A simulator for transcranial electrical stimulation';
           
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @customUpdateAppLayout, true);
           
            app.SimulationtagEditField.Value = "Date and time e.g. 20221225T120100";
            
            %CreateElecTab(app, 1);
            CreateElecTab(app, 2);

           % app.DynamicElecPos = uilabel(app.ElecTab);
            app.PositionEditFieldLabel.Position = [6 115 43 22];
            app.PositionEditFieldLabel.Text = 'Position';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [T1file,T1path] = uigetfile('*.nii');  % Open a T1 MRI file
            app.T1FileLoc.Value = strcat(T1path,T1file);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [T2file,T2path] = uigetfile('*.nii');  % Open a T2 MRI file
            app.T2FileLoc.Value = strcat(T2path,T2file);
        end

        % Value changed function: NumberofelectrodesEditField
        function NumberofelectrodesEditFieldValueChanged(app, event)
            RequiredNumTabs = app.NumberofelectrodesEditField.Value;
            CurrentNumTabs = size(app.DynamicElecTab,2);
            if (mod(RequiredNumTabs, 1) ~= 0) && (RequiredNumTabs < 2) 
                 uif = uifigure();
                uialert(uif,'Please enter a whole number value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            elseif (RequiredNumTabs < 2)
                uif = uifigure();
                uialert(uif,'Please enter a value of 2 or more in "Number of electrodes"','Warning','Icon','warning');
            elseif mod(RequiredNumTabs, 1) ~= 0 
                uif = uifigure();
                uialert(uif,'Please enter a whole number in "Number of electrodes"','Warning','Icon','warning');
            elseif RequiredNumTabs > CurrentNumTabs % Tab creation
                NumNewTabs = RequiredNumTabs - CurrentNumTabs;
                for i = 1:NumNewTabs
                    fprintf("Tab to create: %d \n", (CurrentNumTabs + i))
                    CreateElecTab(app, (CurrentNumTabs + i));
                end
            elseif RequiredNumTabs < CurrentNumTabs % Tab deletion
                NumDeleteTabs = CurrentNumTabs - RequiredNumTabs;
                for i = 1:NumDeleteTabs
                    fprintf("Tab to delete: %d \n", ((CurrentNumTabs+1) - i))
                    DeleteElecTab(app, ((CurrentNumTabs+1) - i));
                end
            end            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if strcmp(value, 'Pad') % Create dropdowm menu for electrode orientation
                app.DynamicOrientationDropDown{1} = uidropdown(app.ElecTab);
                app.DynamicOrientationDropDown{1}.Items = {'Long axis going left/right', 'Long axis going front/back', 'Long axis going up/down'};
                app.DynamicOrientationDropDown{1}.Position = [95 18 175 22];
                app.DynamicOrientationDropDown{1}.Value = 'Long axis going left/right';

                app.DynamicOrientationText{1} = uilabel(app.ElecTab);
                app.DynamicOrientationText{1}.HorizontalAlignment = 'left';
                app.DynamicOrientationText{1}.Position = [8 18 90 22];
                app.DynamicOrientationText{1}.Text = 'Orientation';
            else % Delete dropdowm menu for electrode orientation
                if length(app.DynamicOrientationDropDown)> 0
                    delete(app.DynamicOrientationDropDown{1});
                end
            end
        end

        % Value changed function: PositionEditField
        function PositionEditFieldValueChanged(app, event)
            value = app.PositionEditField.Value;
            ElectrodePos = ["nz" "lpa" "rpa" "Fp1", "Fpz", "Fp2", "F7", "F3", "Fz", "F4", "F8", ...
                "T7", "C3", "Cz", "C4", "T8",  "P7", "P3", "Pz", "P4", "P8", "O1", "Oz", "O2"];
            if strcmp(app.CaptypeDropDown.Value, '1020')
                if ~ismember(value, ElectrodePos)
                    uif = uifigure();
                    uialert(uif,'The electrode position you entered was not valid. Please refer to the electrode position image.','Warning','Icon','warning');
                end
            end
        end

        % Callback function
        function TypeDropDown_2ValueChanged(app, event)
            value = app.TypeDropDown_2.Value;
            if strcmp(value, 'Pad') % Create dropdowm menu for electrode orientation
                app.DynamicOrientationDropDown{2} = uidropdown(app.ElecTab{2});
                app.DynamicOrientationDropDown{2}.Items = {'Long axis going left/right', 'Long axis going front/back', 'Long axis going up/down'};
                app.DynamicOrientationDropDown{2}.Position = [95 18 175 22];
                app.DynamicOrientationDropDown{2}.Value = 'Long axis going left/right';

                app.DynamicOrientationText{2} = uilabel(app.app.ElecTab{2});
                app.DynamicOrientationText{2}.HorizontalAlignment = 'left';
                app.DynamicOrientationText{2}.Position = [8 18 90 22];
                app.DynamicOrientationText{2}.Text = 'Orientation';
            else % Delete dropdowm menu for electrode orientation
                if length(app.DynamicOrientationDropDown)> 1
                    delete(app.DynamicOrientationDropDown{2});
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            %PrintToScreen(app, "------ ATTEMPTING TO RUN ROAST ------");
            T1Location = app.T1FileLoc.Value;
            if length(T1Location) == 0
                %PrintToScreen(app, "Please choose a file location for the T1 MRI.");
                %PrintToScreen(app, "------ ROAST WAS NOT ABLE TO START ------");
                uif = uifigure();
                uialert(uif,'ROAST cannot run as a T1 file location has not been chosen.','Warning','Icon','warning');
                return;
            end

            T2Location = app.T2FileLoc.Value;
            %if length(T2Location) == 0
                %PrintToScreen(app, "T2 file location not chosen. Proceeding without T2 MRI.");
            %end

            Electrode1Intensity = app.StimulationintensitymAEditField.Value;
            Electrode2Intensity = app.StimulationintensitymAEditField_2.Value;

            if (Electrode1Intensity + Electrode2Intensity) ~= 0
                uif = uifigure();
                uialert(uif,'ROAST cannot run as the sum of electrode intensities across electrodes is not equal to 0.','Warning','Icon','warning');
                %PrintToScreen(app, "Please ensure the sum of electrode intensities are equal to 0");
                %PrintToScreen(app, "------ ROAST WAS NOT ABLE TO START ------");
                return;
            end
            Electrode1Position = app.PositionEditField.Value;

            Electrode1Type = app.TypeDropDown.Value;
            text = strcat("(Electrode 1) Position = ", string(Electrode1Position), " Intensity = ", string(Electrode1Intensity), " Type = ", string(Electrode1Type));
            if strcmp(Electrode1Type, "Pad")
                Electrode1Orientation = app.DynamicOrientationDropDown{1}.Value;
                text = strcat(text, " Orientation = ", string(Electrode1Orientation));
            end
            %PrintToScreen(app, text);

            Electrode2Position = app.PositionEditField_2.Value;

            Electrode2Type = app.TypeDropDown_2.Value;
            text = strcat("(Electrode 2) Position = ", string(Electrode2Position), " Intensity = ", string(Electrode2Intensity), " Type = ", string(Electrode2Type));
            if strcmp(Electrode2Type, "Pad")
                Electrode2Orientation = app.DynamicOrientationDropDown{2}.Value;
                text = strcat(text, " Orientation = ", string(Electrode1Orientation));
            end
            %PrintToScreen(app, text);

            %% Check if any advanced settings have been changed
            advancedRoastInput = [];
            if app.ResamplingCheckBox.Value
                advancedRoastInput = [advancedRoastInput, 'resampling', 'on'];
                %PrintToScreen(app, "Advanced setting: Resampling ON");
            end

            if app.ZeropaddingEditField.Value ~= 0
                advancedRoastInput = [advancedRoastInput, 'zeropadding', app.ZeropaddingEditField.Value];
                %PrintToScreen(app, strcat("Advanced setting: Zero padding = ", string(app.ZeropaddingEditField.Value)));
            end

            if ~strcmp(app.SimulationtagEditField.Value, "Date and time e.g. 20221225T120100")
                advancedRoastInput = [advancedRoastInput, 'simulationTag', string(pap.SimulationtagEditField.Value)];
            end
            % Check each conductivity value and save to struct if they have
            % been changed from default
            NewConductivies = struct();
            if app.WhitematterEditField.Value ~= 0.126
                %PrintToScreen(app, strcat("Advanced setting: White matter conductivity has been changed to ", string(app.WhitematterEditField.Value), " S/m"));
                NewConductivies.white = app.WhitematterEditField.Value;
            end
            if app.GraymatterEditField.Value ~= 0.276
                %PrintToScreen(app, strcat("Advanced setting: Grey matter conductivity has been changed to ", string(app.GraymatterEditField.Value), " S/m"));
                NewConductivies.gray = app.GraymatterEditField.Value;
            end
            if app.CerebrospinalfluidEditField.Value ~= 1.65
                %PrintToScreen(app, strcat("Advanced setting: Cerebrospinal fluid conductivity has been changed to ", string(app.CerebrospinalfluidEditField.Value), " S/m"));
                NewConductivies.csf = app.CerebrospinalfluidEditField.Value;
            end
            if app.BoneEditField.Value ~= 0.01
                %PrintToScreen(app, strcat("Advanced setting: Bone conductivity has been changed to ", string(app.BoneEditField.Value), " S/m"));
                NewConductivies.bone = app.BoneEditField.Value;
            end
            if app.SkinEditField.Value ~= 0.465
                %PrintToScreen(app, strcat("Advanced setting: Skin conductivity has been changed to ", string(app.SkinEditField.Value), " S/m"));
                NewConductivies.skin = app.SkinEditField.Value;
            end
            if app.AirEditField.Value ~= 2.5e-14
                %PrintToScreen(app, strcat("Advanced setting: Air conductivity has been changed to ", string(app.AirEditField.Value), " S/m"));
                NewConductivies.air = app.AirEditField.Value;
            end
            if app.GelEditField.Value ~= 0.3
                %PrintToScreen(app, strcat("Advanced setting: Gel conductivity has been changed to ", string(app.GelEditField.Value), " S/m"));
                NewConductivies.gel = app.GelEditField.Value;
            end
            if app.ElectrodeEditField.Value ~= 5.9e7
                %PrintToScreen(app, strcat("Advanced setting: Electrode conductivity has been changed to ", string(app.ElectrodeEditField.Value), " S/m"));
                NewConductivies.electrode = app.ElectrodeEditField.Value;
            end

            % Check each mesh option and save to struct if they have
            % been changed from default
            NewMeshOptions = struct();
            if app.MaximalsurfaceelementsizeEditField.Value ~= 5
                %PrintToScreen(app, strcat("Advanced setting: Maximal surface element size has been changed to ", string(app.MaximalsurfaceelementsizeEditField.Value), " S/m"));
                NewMeshOptions.radbound = app.MaximalsurfaceelementsizeEditField.Value;
            end
            if app.MinimalangleofasurfacetriangleEditField.Value ~= 30
                %PrintToScreen(app, strcat("Advanced setting: Minimum angle of a surface triangle has been changed to ", string(app.MinimalangleofasurfacetriangleEditField.Value), "S /m"));
                NewMeshOptions.angbound = app.MinimalangleofasurfacetriangleEditField.Value;
            end
            if app.MaximumdistancebetweenEditField.Value ~= 0.3
                %PrintToScreen(app, strcat("Advanced setting: Maximal distance surface bounding circle and element bounding sphere centers has been changed to ", string(app.MaximumdistancebetweenEditField.Value), " S/m"));
                NewMeshOptions.distbound = app.MaximumdistancebetweenEditField.Value;
            end
            if app.MaximalradiusedgeratioEditField.Value ~= 3
                %PrintToScreen(app, strcat("Advanced setting: Maximal radius-edge ratio has been changed to ", string(app.MaximalradiusedgeratioEditField.Value), " S/m"));
                NewMeshOptions.reratio = app.MaximalradiusedgeratioEditField.Value;
            end
            if app.MaximaltetrahedralelementvolumeEditField.Value ~= 10
                %PrintToScreen(app, strcat("Advanced setting: Maximal tetrahedral element volume has been changed to ", string(app.MaximaltetrahedralelementvolumeEditField.Value), " S/m"));
                NewMeshOptions.maxvol = app.MaximaltetrahedralelementvolumeEditField.Value;
            end


            %roast(T1Location, {string(Electrode1Position), Electrode1Intensity, string(Electrode2Position), Electrode2Intensity})
        end

        % Callback function
        function ImageClicked(app, event)

            FigurePosition = app.UIFigure.Position; % Current position and size of app window
            
            % As the width of the window changes the position of the click
            % changes. This calculates the difference so regardless of
            % window size the click position always aligns with electrode
            % in image
            XDif = FigurePosition(3)-840;
            if XDif ~= 0
                XDif = round((FigurePosition(3)-840)/2);
            end
            ClickPos = app.UIFigure.CurrentPoint % Position of click
            %disp(app.Image.Position)
            %disp(app.TabGroup3.Position)


            PosToUpdate = 'NoUpdate'; % Default to not updating electrode position
            
            %% Track click location and save new electrode position in  PosToUpdate
            imgRatio =  683 / 638;
            tabRatio = app.TabGroup3.Position(3) / app.TabGroup3.Position(4);
            
            if tabRatio > imgRatio 
                fprintf("Spaces to the side");
                xShift = round((app.TabGroup3.Position(3)-(app.TabGroup3.Position(4)*(1/imgRatio)))/2);
                YClickPerc = ClickPos(2) / (app.Image.Position(4) / 100);
                XClickPerc = (ClickPos(1) - xShift - 314) / (((app.TabGroup3.Position(4)*(1/imgRatio))) / 100);
            else
                fprintf("Spaces above and below");
                YClickPerc = round((app.TabGroup3.Position(4)-(app.TabGroup3.Position(3)*imgRatio))/2);
            end

            if (YClickPerc > 46) && (YClickPerc < 56) % Middle row Y range
                if(XClickPerc > 9) && (XClickPerc < 19)
                    PosToUpdate = 'T3';
                elseif(XClickPerc > 28) && (XClickPerc < 38)
                    PosToUpdate = 'C3';
                elseif(XClickPerc > 46) && (XClickPerc < 56)
                    PosToUpdate = 'Cz';
                elseif(XClickPerc > 64) && (XClickPerc < 74)
                    PosToUpdate = 'C4';
                elseif(XClickPerc > 83) && (XClickPerc < 93)
                    PosToUpdate = 'T4';
                end
            end
            if (YClickPerc > 65) && (YClickPerc < 75) % F3, Fz, F4
                    if (XClickPerc > 31) && (XClickPerc < 41)
                        PosToUpdate = 'F3';
                    elseif(XClickPerc > 46) && (XClickPerc < 56)
                        PosToUpdate = 'Fz';
                    elseif(XClickPerc > 61) && (XClickPerc < 71)
                        PosToUpdate = 'F4';
                    end
            end   
            if (ClickPos(2) > 290) && (ClickPos(2) < 330) % P3, Pz, P4
                    if (ClickPos(1) > (515 + XDif)) && (ClickPos(1) < (555 + XDif))
                        PosToUpdate = 'P3';
                    elseif(ClickPos(1) > (570 + XDif)) && (ClickPos(1) < (610 + XDif))
                        PosToUpdate = 'Pz';
                    elseif(ClickPos(1) > (630 + XDif)) && (ClickPos(1) < (670 + XDif))
                        PosToUpdate = 'P4';
                    end
            end
            if (ClickPos(2) > 230) && (ClickPos(2) < 270) % O1, O2
                    if (ClickPos(1) > (620 + XDif)) && (ClickPos(1) < (660 + XDif))
                        PosToUpdate = 'O2';
                    elseif(ClickPos(1) > (525 + XDif)) && (ClickPos(1) < (565 + XDif))
                        PosToUpdate = 'O1';
                    end
            end
            if (ClickPos(2) > 500) && (ClickPos(2) < 540) % Fp1, Fp2
                    if (ClickPos(1) > (620 + XDif)) && (ClickPos(1) < (660 + XDif))
                        PosToUpdate = 'Fp2';
                    elseif(ClickPos(1) > (525 + XDif)) && (ClickPos(1) < (565 + XDif))
                        PosToUpdate = 'Fp1';
                    end   
            end
            if (ClickPos(2) > 280) && (ClickPos(2) < 320) % T5, T6
                    if (ClickPos(1) > (455 + XDif)) && (ClickPos(1) < (495 + XDif))
                        PosToUpdate = 'T5';
                    elseif(ClickPos(1) > (690 + XDif)) && (ClickPos(1) < (730 + XDif))
                        PosToUpdate = 'T6';
                    end   
            end
            if (ClickPos(2) > 450) && (ClickPos(2) < 490) % F7, F8
                    if (ClickPos(1) > (455 + XDif)) && (ClickPos(1) < (495 + XDif))
                        PosToUpdate = 'F7';
                    elseif(ClickPos(1) > (690 + XDif)) && (ClickPos(1) < (730 + XDif))
                        PosToUpdate = 'F8';
                    end    
            end
            
            %% Update electrode position in relevant electrode tab if needed
            if ~strcmp(PosToUpdate, 'NoUpdate')
                CurrentTab = str2double(app.ElectrodeTabGroup.SelectedTab.Title);
                HighlightElectrodePos(app, PosToUpdate);
                if CurrentTab == 1
                    app.PositionEditField.Value = PosToUpdate;
                else
                    app.ElecPosValue{CurrentTab}.Value = PosToUpdate;
                end
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {553, 553};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {314, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.MainmenuTab = uitab(app.TabGroup2);
            app.MainmenuTab.Title = 'Main menu';
            app.CaptypeDropDownLabel = uilabel(app.MainmenuTab);
            app.CaptypeDropDownLabel.Text = 'Cap type';
            app.CaptypeDropDown = uidropdown(app.MainmenuTab);
            app.CaptypeDropDown.Items = {'1010 (default)', '1020', '1005', 'BioSemi', 'EGI'};
            app.CaptypeDropDown.Value = '1010 (default)';
            app.NumberofelectrodesEditFieldLabel = uilabel(app.MainmenuTab);
            app.NumberofelectrodesEditFieldLabel.Text = 'Number of electrodes';
            app.NumberofelectrodesEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.NumberofelectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofelectrodesEditFieldValueChanged, true);
            app.NumberofelectrodesEditField.Value = 2;
            app.ElectrodeconfigurationLabel = uilabel(app.MainmenuTab);
            app.ElectrodeconfigurationLabel.Text = 'Electrode configuration';
            app.ElectrodeTabGroup = uitabgroup(app.MainmenuTab);
            app.ElecTab = uitab(app.ElectrodeTabGroup);
            app.ElecTab.Title = '1';
            app.TypeDropDownLabel = uilabel(app.ElecTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.ElecTab);
            app.TypeDropDown.Items = {'Disc (default)', 'Pad', 'Ring'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Disc (default)';
            app.StimulationintensitymAEditFieldLabel = uilabel(app.ElecTab);
            app.StimulationintensitymAEditFieldLabel.Text = 'Stimulation intensity (mA)';
            app.StimulationintensitymAEditField = uieditfield(app.ElecTab, 'numeric');
            app.PositionEditFieldLabel = uilabel(app.ElecTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.ElecTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @PositionEditFieldValueChanged, true);
            app.StartButton = uibutton(app.MainmenuTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.MainmenuTab, 'push');
            app.StopButton.Text = 'Stop';
            app.BrowseButton = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.T1FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.BrowseButton_2 = uibutton(app.MainmenuTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.T2FileLoc = uieditfield(app.MainmenuTab, 'text');
            app.SelectT1MRIfileLabel = uilabel(app.MainmenuTab);
            app.SelectT1MRIfileLabel.Text = 'Select T1 MRI file';
            app.OptionalSelectT2MRIfileLabel = uilabel(app.MainmenuTab);
            app.OptionalSelectT2MRIfileLabel.Text = '(Optional) Select T2 MRI file';
            app.SavesettingsButton = uibutton(app.MainmenuTab, 'push');
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsButton = uibutton(app.MainmenuTab, 'push');
            app.LoadsettingsButton.Text = 'Load settings';
            app.AdvancedsettingsTab = uitab(app.TabGroup2);
            app.AdvancedsettingsTab.Title = 'Advanced settings';
            app.SimulationtagEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.SimulationtagEditFieldLabel.Text = 'Simulation tag';
            app.SimulationtagEditField = uieditfield(app.AdvancedsettingsTab, 'text');
            app.ResamplingCheckBox = uicheckbox(app.AdvancedsettingsTab);
            app.ResamplingCheckBox.Text = 'Resampling';
            app.ZeropaddingEditFieldLabel = uilabel(app.AdvancedsettingsTab);
            app.ZeropaddingEditFieldLabel.Text = 'Zero padding';
            app.ZeropaddingEditField = uieditfield(app.AdvancedsettingsTab, 'numeric');
            app.TabGroup4 = uitabgroup(app.AdvancedsettingsTab);
            app.ConductivityvaluesTab = uitab(app.TabGroup4);
            app.ConductivityvaluesTab.Title = 'Conductivity values';
            app.WhitematterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.WhitematterEditFieldLabel.Text = 'White matter';
            app.WhitematterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.WhitematterEditField.Value = 0.126;
            app.GraymatterEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GraymatterEditFieldLabel.Text = 'Gray matter';
            app.GraymatterEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GraymatterEditField.Value = 0.276;
            app.CerebrospinalfluidLabel = uilabel(app.ConductivityvaluesTab);
            app.CerebrospinalfluidLabel.Text = 'Cerebrospinal fluid ';
            app.CerebrospinalfluidEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.CerebrospinalfluidEditField.Value = 1.65;
            app.BoneEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.BoneEditFieldLabel.Text = 'Bone';
            app.BoneEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.BoneEditField.Value = 0.01;
            app.SkinEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.SkinEditFieldLabel.Text = 'Skin';
            app.SkinEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.SkinEditField.Value = 0.465;
            app.AirEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.AirEditFieldLabel.Text = 'Air';
            app.AirEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.AirEditField.Value = 2.5e-14;
            app.GelEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.GelEditFieldLabel.Text = 'Gel';
            app.GelEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.GelEditField.Value = 0.3;
            app.ElectrodeEditFieldLabel = uilabel(app.ConductivityvaluesTab);
            app.ElectrodeEditFieldLabel.Text = 'Electrode';
            app.ElectrodeEditField = uieditfield(app.ConductivityvaluesTab, 'numeric');
            app.ElectrodeEditField.Value = 59000000;
            app.SmLabel = uilabel(app.ConductivityvaluesTab);
            app.SmLabel.Text = 'S/m';
            app.SmLabel_2 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_2.Text = 'S/m';
            app.SmLabel_3 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_3.Text = 'S/m';
            app.SmLabel_4 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_4.Text = 'S/m';
            app.SmLabel_5 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_5.Text = 'S/m';
            app.SmLabel_6 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_6.Text = 'S/m';
            app.SmLabel_7 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_7.Text = 'S/m';
            app.SmLabel_8 = uilabel(app.ConductivityvaluesTab);
            app.SmLabel_8.Text = 'S/m';
            app.MeshoptionsTab = uitab(app.TabGroup4);
            app.MeshoptionsTab.Title = 'Mesh options';
            app.MaximalsurfaceelementsizeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximalsurfaceelementsizeEditFieldLabel.Text = 'Maximal surface element size';
            app.MaximalsurfaceelementsizeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximalsurfaceelementsizeEditField.Value = 5;
            app.MinimalangleofasurfacetriangleEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MinimalangleofasurfacetriangleEditFieldLabel.Text = 'Minimal angle of a surface triangle';
            app.MinimalangleofasurfacetriangleEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MinimalangleofasurfacetriangleEditField.Value = 30;
            app.MaximumdistancebetweenEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximumdistancebetweenEditFieldLabel.Text = {'Maximal distance between the '; 'center of the surface bounding circle '; 'and center of the element bounding '; 'sphere'};
            app.MaximumdistancebetweenEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximumdistancebetweenEditField.Value = 0.3;
            app.MaximalradiusedgeratioEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximalradiusedgeratioEditFieldLabel.Text = 'Maximal radius-edge ratio';
            app.MaximalradiusedgeratioEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximalradiusedgeratioEditField.Value = 3;
            app.MaximaltetrahedralelementvolumeEditFieldLabel = uilabel(app.MeshoptionsTab);
            app.MaximaltetrahedralelementvolumeEditFieldLabel.Text = 'Maximal tetrahedral element volume';
            app.MaximaltetrahedralelementvolumeEditField = uieditfield(app.MeshoptionsTab, 'numeric');
            app.MaximaltetrahedralelementvolumeEditField.Value = 10;
            app.InfoTab = uitab(app.TabGroup2);
            app.InfoTab.Title = 'Info';
            app.Hyperlink = uihyperlink(app.InfoTab);
            app.Hyperlink.URL = 'https://groups.google.com/g/roast-users';
            app.Hyperlink.Text = 'Roast users Google group';
            app.Hyperlink2 = uihyperlink(app.InfoTab);
            app.Hyperlink2.URL = 'https://github.com/andypotatohy/roast';
            app.Hyperlink2.Text = 'Roast github repository with examples';
            app.Hyperlink3 = uihyperlink(app.InfoTab);
            app.Hyperlink3.Text = 'GUI Roast video tutorial';
            app.Hyperlink3_2 = uihyperlink(app.InfoTab);
            app.Hyperlink3_2.Text = 'GUI Roast github repository';
            app.HelpfullinksLabel_2 = uilabel(app.InfoTab);
            app.HelpfullinksLabel_2.Text = 'Helpful links';
            app.Label = uilabel(app.InfoTab);
            app.Label.Text = {'If you use ROAST in your research, please cite these:'; ''; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., Realistic '; 'vOlumetric-Approach to Simulate Transcranial Electric Stimulation -- '; 'ROAST -- a fully automated open-source pipeline, Journal of Neural '; 'Engineering, Vol. 16, No. 5, 2019 (prefered reference)'; ''; 'Huang, Y., Datta, A., Bikson, M., Parra, L.C., ROAST: an open-source, '; 'fully-automated, Realistic vOlumetric-Approach-based Simulator for TES, '; 'Proceedings of the 40th Annual International Conference of the IEEE '; 'Engineering in Medicine and Biology Society, Honolulu, HI, July 2018'; ''; 'If you use New York head to run simulation, please also cite the '; 'following:'; 'Huang, Y., Parra, L.C., Haufe, S.,2016. The New York Head - A precise '; 'standardized volume conductor model for EEG source localization and '; 'tES targeting. NeuroImage,140, 150-162'; ''; 'If you also use the targeting feature (roast_target), '; 'please cite these:'; ''; 'Dmochowski, J.P., Datta, A., Bikson, M., Su, Y., Parra, L.C., '; 'Optimized multi-electrode stimulation increases focality and intensity at '; 'target, Journal of Neural Engineering 8 (4), 046011, 2011'; ''; 'Dmochowski, J.P., Datta, A., Huang, Y., Richardson, J.D., Bikson, M., '; 'Fridriksson, J., Parra, L.C., Targeted transcranial direct current '; 'stimulation for rehabilitation after stroke, NeuroImage, 75, 12-19, 2013'; ''; 'Huang, Y., Thomas, C., Datta, A., Parra, L.C., Optimized tDCS for '; 'Targeting Multiple Brain Regions: An Integrated Implementation. '; 'Proceedings of the 40th Annual International Conference of the '; 'IEEE Engineering in Medicine and Biology Society, Honolulu, HI, '; 'July 2018, 3545-3548'; ''; 'ROAST was supported by NIH through grants R01MH111896, '; 'R01MH111439, R01NS095123, R44NS092144, R41NS076123, '; 'and by Soterix Medical Inc.'};
            app.AcknowledgementsLabel = uilabel(app.InfoTab);
            app.AcknowledgementsLabel.Text = 'Acknowledgements';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.ElectrodepositionTab = uitab(app.TabGroup3);
            app.ElectrodepositionTab.Title = 'Electrode position';
            app.ClicktoselectLabel = uilabel(app.ElectrodepositionTab);
            app.ClicktoselectLabel.Text = 'Click to select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoastGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end